classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        nEditField       matlab.ui.control.NumericEditField
        nEditFieldLabel  matlab.ui.control.Label
        Button           matlab.ui.control.Button
        Graph            matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            addpath gens\
            n = app.nEditField.Value;
            y0 = 0.1;
            X = 0:1/n:1;
            Y = generateYby_analytical(n, y0);
            Y1 = generateYbyAdams(n, y0);
            Y2 = generateYbyCauchy(n, y0);
            Y3 = generateYbyEuler(n, y0);
            Y4 = generateYbyEuler_implicit(n, y0);
            Y5 = generateYbyEuler_recalc(n, y0);
            Y6 = generateYbyRunge_Kutt(n, y0);
            Y7 = generateYbyTaylor(n, y0);
            plot( ...
                app.Graph, ...
                X, Y, '-o',...
                X, Y1, ...
                X, Y2, ...
                X, Y3, ...
                X, Y4, ...
                X, Y5, ...
                X, Y6, ...
                X, Y7, ...
                LineWidth=2);
            legend( ...
                app.Graph, ...
                {'Аналитическое решение' ...
                'Адамс', ...
                'Коши', ...
                'Эйлер', ...
                'Эйлер (неявный)', ...
                'Эйлер (с пересчётом)', ...
                'Рунге-Кутт', ...
                'Тейлор'}, ...
                'Location', 'northwest', 'NumColumns', 2);
            grid(app.Graph, "on");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Graph = uiaxes(app.UIFigure);
            xlabel(app.Graph, 'X')
            ylabel(app.Graph, 'Y')
            zlabel(app.Graph, 'Z')
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'Построить';
            app.nEditFieldLabel = uilabel(app.UIFigure);
            app.nEditFieldLabel.Text = 'n';
            app.nEditField = uieditfield(app.UIFigure, 'numeric');
            app.nEditField.Value = 10;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end